classdef CovidMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParametersPanel                 matlab.ui.container.Panel
        InitialCRc0EditFieldLabel       matlab.ui.control.Label
        c0Field                         matlab.ui.control.NumericEditField
        MinimumCRcbEditFieldLabel       matlab.ui.control.Label
        cbField                         matlab.ui.control.NumericEditField
        CRTimeConstantr1EditFieldLabel  matlab.ui.control.Label
        r1Field                         matlab.ui.control.NumericEditField
        InitialDRdI0EditFieldLabel      matlab.ui.control.Label
        dI0Field                        matlab.ui.control.NumericEditField
        FastestDRdIFEditFieldLabel      matlab.ui.control.Label
        dIFField                        matlab.ui.control.NumericEditField
        DRTimeConstantr2EditFieldLabel  matlab.ui.control.Label
        r2Field                         matlab.ui.control.NumericEditField
        ProbTransmissionbetaEditFieldLabel  matlab.ui.control.Label
        betaField                       matlab.ui.control.NumericEditField
        QuarantineRateqEditFieldLabel   matlab.ui.control.Label
        qField                          matlab.ui.control.NumericEditField
        EtoIRatesigmaEditFieldLabel     matlab.ui.control.Label
        sigmaField                      matlab.ui.control.NumericEditField
        QReleaseRlambdaEditFieldLabel   matlab.ui.control.Label
        lambdaField                     matlab.ui.control.NumericEditField
        ProbSympInfectionrhoEditFieldLabel  matlab.ui.control.Label
        rhoField                        matlab.ui.control.NumericEditField
        RecRSymptgammaIEditFieldLabel   matlab.ui.control.Label
        gammaIField                     matlab.ui.control.NumericEditField
        RecRAsymptgammaAEditFieldLabel  matlab.ui.control.Label
        gammaAField                     matlab.ui.control.NumericEditField
        RecRQuargammaHEditFieldLabel    matlab.ui.control.Label
        gammaHField                     matlab.ui.control.NumericEditField
        DeathRatealphaEditFieldLabel    matlab.ui.control.Label
        alphaField                      matlab.ui.control.NumericEditField
        TransRAsymptthetaEditFieldLabel  matlab.ui.control.Label
        thetaField                      matlab.ui.control.NumericEditField
        TransRExposednuEditFieldLabel   matlab.ui.control.Label
        nuField                         matlab.ui.control.NumericEditField
        ResetParamsButton               matlab.ui.control.Button
        ParamSettingDropDownLabel       matlab.ui.control.Label
        ParamDropDown                   matlab.ui.control.DropDown
        EqtoHRatedeltaQEditFieldLabel   matlab.ui.control.Label
        deltaQField                     matlab.ui.control.NumericEditField
        DisplayPanel                    matlab.ui.container.Panel
        SAxes                           matlab.ui.control.UIAxes
        EAxes                           matlab.ui.control.UIAxes
        IAxes                           matlab.ui.control.UIAxes
        AAxes                           matlab.ui.control.UIAxes
        SqAxes                          matlab.ui.control.UIAxes
        EqAxes                          matlab.ui.control.UIAxes
        HAxes                           matlab.ui.control.UIAxes
        RAxes                           matlab.ui.control.UIAxes
        InitialConditionsPanel          matlab.ui.container.Panel
        SusceptiblesS0Label             matlab.ui.control.Label
        S0Field                         matlab.ui.control.NumericEditField
        ExposedE0Label                  matlab.ui.control.Label
        E0Field                         matlab.ui.control.NumericEditField
        InfectedI0Label                 matlab.ui.control.Label
        I0Field                         matlab.ui.control.NumericEditField
        AsymptomaticA0Label             matlab.ui.control.Label
        A0Field                         matlab.ui.control.NumericEditField
        SusceptiblesQuarSq0Label        matlab.ui.control.Label
        Sq0Field                        matlab.ui.control.NumericEditField
        ExposedQuarEq0Label             matlab.ui.control.Label
        Eq0Field                        matlab.ui.control.NumericEditField
        HospitalizedH0EditFieldLabel    matlab.ui.control.Label
        H0Field                         matlab.ui.control.NumericEditField
        RecoveredR0Label                matlab.ui.control.Label
        R0Field                         matlab.ui.control.NumericEditField
        ICSettingDropDownLabel          matlab.ui.control.Label
        ICDropDown                      matlab.ui.control.DropDown
        ResetICButton                   matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        PlotLabel                       matlab.ui.control.Label
        LabelField                      matlab.ui.control.EditField
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeField                       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        AllAxes
    end
    
    methods (Access = private)
        
        function params = getParamsFromFields(app)
            params = struct();
            
            % Contact rate
            params.c0 = app.c0Field.Value;
            params.cb = app.cbField.Value;
            params.r1 = app.r1Field.Value;
            
            % Diagnosis rate
            params.deltaI0 = app.dI0Field.Value;
            params.deltaIF = app.dIFField.Value;
            params.r2 = app.r2Field.Value;
            
            % Reproduction ratio
            params.beta = app.betaField.Value;
            params.rho = app.rhoField.Value;
            params.nu = app.nuField.Value;
            params.q = app.qField.Value;
            params.theta = app.thetaField.Value;
            params.alpha = app.alphaField.Value;
            params.gammaI = app.gammaIField.Value;
            params.gammaA = app.gammaAField.Value;
            
            % Full model
            params.lambda = app.lambdaField.Value;
            params.sigma = app.sigmaField.Value;
            params.deltaQ = app.deltaQField.Value;
            params.gammaH = app.gammaHField.Value;
        end
        
        function ics = getICsFromFields(app, params)
            % Initial Conditions
            params.S0 = app.S0Field.Value;
            
            E0 = app.E0Field.Value;
            I0 = app.I0Field.Value;
            A0 = app.A0Field.Value;
            Sq0 = app.Sq0Field.Value;
            Eq0 = app.Eq0Field.Value;
            H0 = app.H0Field.Value;
            R0 = app.R0Field.Value;
            
            ics = [params.S0, E0, I0, A0, Sq0, Eq0, H0, R0];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AllAxes = {...
                app.SAxes,...
                app.EAxes,...
                app.IAxes,...
                app.AAxes,...
                app.SqAxes,...
                app.EqAxes,...
                app.HAxes,...
                app.RAxes...
            };
        
            % Load param options
            app.ParamDropDown.Items = {};
            paramFiles = dir('Params');
            c = 1;
            for i=1:length(paramFiles)
                if paramFiles(i).name(1) ~= '.'
                    app.ParamDropDown.Items{c} = paramFiles(i).name;
                    c = c+1;
                end
            end
            
            % Load IC options
            app.ICDropDown.Items = {};
            icFiles = dir('ICs');
            c = 1;
            for i=1:length(icFiles)
                if icFiles(i).name(1) ~= '.'
                    app.ICDropDown.Items{c} = icFiles(i).name;
                    c = c+1;
                end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            tEnd = app.TimeField.Value;
            
            params = getParamsFromFields(app);
            N0 = getICsFromFields(app, params);
            
            [t,N] = ode45(@CovidModel, [0, tEnd], N0, [], params);
            
            for i=1:length(app.AllAxes)
                hold(app.AllAxes{i}, 'on');
                legend(app.AllAxes{i}, 'show');
            end
            
            plot(app.SAxes, t, N(:,1),...
                "DisplayName", app.LabelField.Value);
            plot(app.EAxes, t, N(:,2),...
                "DisplayName", app.LabelField.Value);
            plot(app.IAxes, t, N(:,3),...
                "DisplayName", app.LabelField.Value);
            plot(app.AAxes, t, N(:,4),...
                "DisplayName", app.LabelField.Value);
            plot(app.SqAxes, t, N(:,5),...
                "DisplayName", app.LabelField.Value);
            plot(app.EqAxes, t, N(:,6),...
                "DisplayName", app.LabelField.Value);
            plot(app.HAxes, t, N(:,7),...
                "DisplayName", app.LabelField.Value);
            plot(app.RAxes, t, N(:,8),...
                "DisplayName", app.LabelField.Value);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            for i=1:length(app.AllAxes)
                cla(app.AllAxes{i});
                legend(app.AllAxes{i}, 'hide');
            end
        end

        % Button pushed function: ResetParamsButton
        function ResetParamsButtonPushed(app, event)
            params = GetParams(['Params/',app.ParamDropDown.Value]);
            
            fields = {'c0', 'cb', 'r1', 'dI0', 'dIF', 'r2',...
                'beta', 'q', 'sigma', 'lambda', 'rho',...
                'gammaI', 'gammaA', 'gammaH', 'alpha', 'theta', 'nu',...
                'deltaQ'};
            
            appFields = {app.c0Field, app.cbField, app.r1Field,...
                app.dI0Field, app.dIFField, app.r2Field,...
                app.betaField, app.qField, app.sigmaField,...
                app.lambdaField, app.rhoField,...
                app.gammaIField, app.gammaAField, app.gammaHField,...
                app.alphaField, app.thetaField, app.nuField,...
                app.deltaQField};
            
            for i=1:length(fields)
                if isfield(params, fields{i})
                    appFields{i}.Value = params.(fields{i});
                end
            end
        end

        % Button pushed function: ResetICButton
        function ResetICButtonPushed(app, event)
            ics = GetParams(['ICs/',app.ICDropDown.Value]);
            
            fields = {'S0', 'E0', 'I0', 'A0', 'Sq0', 'Eq0', 'H0', 'R0'};
            
            appFields = {app.S0Field, app.E0Field, app.I0Field,...
                app.A0Field, app.Sq0Field, app.Eq0Field,...
                app.H0Field, app.R0Field};
            
            for i=1:length(fields)
                if isfield(ics, fields{i})
                    appFields{i}.Value = ics.(fields{i});
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.InitialCRc0EditFieldLabel = uilabel(app.ParametersPanel);
            app.InitialCRc0EditFieldLabel.Text = 'Initial CR (c0)';
            app.c0Field = uieditfield(app.ParametersPanel, 'numeric');
            app.c0Field.Value = 13;
            app.MinimumCRcbEditFieldLabel = uilabel(app.ParametersPanel);
            app.MinimumCRcbEditFieldLabel.Text = 'Minimum CR(cb)';
            app.cbField = uieditfield(app.ParametersPanel, 'numeric');
            app.cbField.Value = 13;
            app.CRTimeConstantr1EditFieldLabel = uilabel(app.ParametersPanel);
            app.CRTimeConstantr1EditFieldLabel.Text = 'CR Time Constant (r1)';
            app.r1Field = uieditfield(app.ParametersPanel, 'numeric');
            app.r1Field.Value = 1.3768;
            app.InitialDRdI0EditFieldLabel = uilabel(app.ParametersPanel);
            app.InitialDRdI0EditFieldLabel.Text = 'Initial DR (dI0)';
            app.dI0Field = uieditfield(app.ParametersPanel, 'numeric');
            app.dI0Field.Value = 0.1382;
            app.FastestDRdIFEditFieldLabel = uilabel(app.ParametersPanel);
            app.FastestDRdIFEditFieldLabel.Text = 'Fastest DR (dIF)';
            app.dIFField = uieditfield(app.ParametersPanel, 'numeric');
            app.dIFField.Value = 0.1382;
            app.DRTimeConstantr2EditFieldLabel = uilabel(app.ParametersPanel);
            app.DRTimeConstantr2EditFieldLabel.Text = 'DR Time Constant (r2)';
            app.r2Field = uieditfield(app.ParametersPanel, 'numeric');
            app.r2Field.Value = 0.3283;
            app.ProbTransmissionbetaEditFieldLabel = uilabel(app.ParametersPanel);
            app.ProbTransmissionbetaEditFieldLabel.Text = 'Prob Transmission (beta)';
            app.betaField = uieditfield(app.ParametersPanel, 'numeric');
            app.betaField.Value = 2.0389e-09;
            app.QuarantineRateqEditFieldLabel = uilabel(app.ParametersPanel);
            app.QuarantineRateqEditFieldLabel.Text = 'Quarantine Rate (q)';
            app.qField = uieditfield(app.ParametersPanel, 'numeric');
            app.qField.Value = 2e-06;
            app.EtoIRatesigmaEditFieldLabel = uilabel(app.ParametersPanel);
            app.EtoIRatesigmaEditFieldLabel.Text = 'E to I Rate (sigma)';
            app.sigmaField = uieditfield(app.ParametersPanel, 'numeric');
            app.sigmaField.Value = 0.2;
            app.QReleaseRlambdaEditFieldLabel = uilabel(app.ParametersPanel);
            app.QReleaseRlambdaEditFieldLabel.Text = 'Q Release R (lambda)';
            app.lambdaField = uieditfield(app.ParametersPanel, 'numeric');
            app.lambdaField.Value = 0.0714;
            app.ProbSympInfectionrhoEditFieldLabel = uilabel(app.ParametersPanel);
            app.ProbSympInfectionrhoEditFieldLabel.Text = 'Prob Symp Infection (rho)';
            app.rhoField = uieditfield(app.ParametersPanel, 'numeric');
            app.rhoField.Value = 0.6834;
            app.RecRSymptgammaIEditFieldLabel = uilabel(app.ParametersPanel);
            app.RecRSymptgammaIEditFieldLabel.Text = 'Rec R Sympt (gammaI)';
            app.gammaIField = uieditfield(app.ParametersPanel, 'numeric');
            app.gammaIField.Value = 0.1029;
            app.RecRAsymptgammaAEditFieldLabel = uilabel(app.ParametersPanel);
            app.RecRAsymptgammaAEditFieldLabel.Text = 'Rec R Asympt (gammaA)';
            app.gammaAField = uieditfield(app.ParametersPanel, 'numeric');
            app.gammaAField.Value = 0.2978;
            app.RecRQuargammaHEditFieldLabel = uilabel(app.ParametersPanel);
            app.RecRQuargammaHEditFieldLabel.Text = 'Rec R Quar (gammaH)';
            app.gammaHField = uieditfield(app.ParametersPanel, 'numeric');
            app.gammaHField.Value = 0.1024;
            app.DeathRatealphaEditFieldLabel = uilabel(app.ParametersPanel);
            app.DeathRatealphaEditFieldLabel.Text = 'Death Rate (alpha)';
            app.alphaField = uieditfield(app.ParametersPanel, 'numeric');
            app.alphaField.Value = 0.0009;
            app.TransRAsymptthetaEditFieldLabel = uilabel(app.ParametersPanel);
            app.TransRAsymptthetaEditFieldLabel.Text = 'Trans R Asympt (theta)';
            app.thetaField = uieditfield(app.ParametersPanel, 'numeric');
            app.thetaField.Value = 1.6003;
            app.TransRExposednuEditFieldLabel = uilabel(app.ParametersPanel);
            app.TransRExposednuEditFieldLabel.Text = 'Trans R Exposed (nu)';
            app.nuField = uieditfield(app.ParametersPanel, 'numeric');
            app.nuField.Value = 1.5008;
            app.ResetParamsButton = uibutton(app.ParametersPanel, 'push');
            app.ResetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParamsButtonPushed, true);
            app.ResetParamsButton.Text = 'Reset Params';
            app.ParamSettingDropDownLabel = uilabel(app.ParametersPanel);
            app.ParamSettingDropDownLabel.Text = 'Param Setting';
            app.ParamDropDown = uidropdown(app.ParametersPanel);
            app.EqtoHRatedeltaQEditFieldLabel = uilabel(app.ParametersPanel);
            app.EqtoHRatedeltaQEditFieldLabel.Text = 'Eq to H Rate (deltaQ)';
            app.deltaQField = uieditfield(app.ParametersPanel, 'numeric');
            app.deltaQField.Value = 0.1259;
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.SAxes = uiaxes(app.DisplayPanel);
            title(app.SAxes, 'Susceptibles S')
            xlabel(app.SAxes, 'Time (days)')
            ylabel(app.SAxes, 'Number')
            app.EAxes = uiaxes(app.DisplayPanel);
            title(app.EAxes, 'Exposed E')
            xlabel(app.EAxes, 'Time (days)')
            ylabel(app.EAxes, 'Number')
            app.IAxes = uiaxes(app.DisplayPanel);
            title(app.IAxes, 'Infected I')
            xlabel(app.IAxes, 'Time (days)')
            ylabel(app.IAxes, 'Number')
            app.AAxes = uiaxes(app.DisplayPanel);
            title(app.AAxes, 'Asymptomatic A')
            xlabel(app.AAxes, 'Time (days)')
            ylabel(app.AAxes, 'Number')
            app.SqAxes = uiaxes(app.DisplayPanel);
            title(app.SqAxes, 'Susceptible (Quarantined) Sq')
            xlabel(app.SqAxes, 'Time (days)')
            ylabel(app.SqAxes, 'Number')
            app.EqAxes = uiaxes(app.DisplayPanel);
            title(app.EqAxes, 'Exposed (Quarantined) Eq')
            xlabel(app.EqAxes, 'Time (days)')
            ylabel(app.EqAxes, 'Number')
            app.HAxes = uiaxes(app.DisplayPanel);
            title(app.HAxes, 'Hospitalized H')
            xlabel(app.HAxes, 'Time (days)')
            ylabel(app.HAxes, 'Number')
            app.RAxes = uiaxes(app.DisplayPanel);
            title(app.RAxes, 'Recovered R')
            xlabel(app.RAxes, 'Time (days)')
            ylabel(app.RAxes, 'Number')
            app.InitialConditionsPanel = uipanel(app.UIFigure);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.SusceptiblesS0Label = uilabel(app.InitialConditionsPanel);
            app.SusceptiblesS0Label.Text = 'Susceptibles (S0)';
            app.S0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.S0Field.Value = 11081000;
            app.ExposedE0Label = uilabel(app.InitialConditionsPanel);
            app.ExposedE0Label.Text = 'Exposed (E0)';
            app.E0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.E0Field.Value = 600;
            app.InfectedI0Label = uilabel(app.InitialConditionsPanel);
            app.InfectedI0Label.Text = 'Infected (I0)';
            app.I0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.I0Field.Value = 410;
            app.AsymptomaticA0Label = uilabel(app.InitialConditionsPanel);
            app.AsymptomaticA0Label.Text = 'Asymptomatic (A0)';
            app.A0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.A0Field.Value = 30;
            app.SusceptiblesQuarSq0Label = uilabel(app.InitialConditionsPanel);
            app.SusceptiblesQuarSq0Label.Text = 'Susceptibles Quar (Sq0)';
            app.Sq0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Sq0Field.Value = 739;
            app.ExposedQuarEq0Label = uilabel(app.InitialConditionsPanel);
            app.ExposedQuarEq0Label.Text = 'Exposed Quar (Eq0)';
            app.Eq0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Eq0Field.Value = 20;
            app.HospitalizedH0EditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.HospitalizedH0EditFieldLabel.Text = 'Hospitalized (H0)';
            app.H0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.H0Field.Value = 41;
            app.RecoveredR0Label = uilabel(app.InitialConditionsPanel);
            app.RecoveredR0Label.Text = 'Recovered (R0)';
            app.R0Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.R0Field.Value = 2;
            app.ICSettingDropDownLabel = uilabel(app.InitialConditionsPanel);
            app.ICSettingDropDownLabel.Text = 'IC Setting';
            app.ICDropDown = uidropdown(app.InitialConditionsPanel);
            app.ResetICButton = uibutton(app.InitialConditionsPanel, 'push');
            app.ResetICButton.ButtonPushedFcn = createCallbackFcn(app, @ResetICButtonPushed, true);
            app.ResetICButton.Text = 'Reset IC';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot All';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear All';
            app.PlotLabel = uilabel(app.UIFigure);
            app.PlotLabel.Text = 'Plot Label';
            app.LabelField = uieditfield(app.UIFigure, 'text');
            app.LabelField.Value = 'Basic Model';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeField = uieditfield(app.UIFigure, 'numeric');
            app.TimeField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end